classdef BirdsLabNeuralToolboxUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BirdsLabNeuralToolboxUIUIFigure  matlab.ui.Figure
        ResetButton                  matlab.ui.control.Button
        ThresholdSignButtonGroup     matlab.ui.container.ButtonGroup
        BothButton                   matlab.ui.control.RadioButton
        PositiveButton               matlab.ui.control.RadioButton
        NegativeButton               matlab.ui.control.RadioButton
        SignalLabel                  matlab.ui.control.Label
        ISIPlotSwitch                matlab.ui.control.Switch
        ISIPlotSwitchLabel           matlab.ui.control.Label
        ThresholduVEditField         matlab.ui.control.NumericEditField
        ThresholduVEditFieldLabel    matlab.ui.control.Label
        SpikeDetectionLabel          matlab.ui.control.Label
        ThresholduVSlider            matlab.ui.control.Slider
        ThresholduVSliderLabel       matlab.ui.control.Label
        PSTHSwitch                   matlab.ui.control.Switch
        PSTHSwitchLabel              matlab.ui.control.Label
        DevelopedbyBirdsLabSchoolofCognitiveSciencesIPMLabel  matlab.ui.control.Label
        rev17Label                   matlab.ui.control.Label
        OnlineSpikeDetectorLabel     matlab.ui.control.Label
        ShownSpikesEditField         matlab.ui.control.NumericEditField
        ShownSpikesEditFieldLabel    matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        DeviceDropDown               matlab.ui.control.DropDown
        DeviceDropDownLabel          matlab.ui.control.Label
        SpikeDetectorSwitch          matlab.ui.control.Switch
        SpikeDetectorLabel           matlab.ui.control.Label
        SpectrumAnalyzerSwitch       matlab.ui.control.Switch
        SpectrumAnalyzerLabel        matlab.ui.control.Label
        TimeScopeSwitch              matlab.ui.control.Switch
        TimeScopeSwitchLabel         matlab.ui.control.Label
        ChannelSelectionButtonGroup  matlab.ui.container.ButtonGroup
        Channel4Button               matlab.ui.control.RadioButton
        Channel3Button               matlab.ui.control.RadioButton
        Channel2Button               matlab.ui.control.RadioButton
        Channel1Button               matlab.ui.control.RadioButton
        SoundCheckBox                matlab.ui.control.CheckBox
        FilterOrderEditField         matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel    matlab.ui.control.Label
        HzFilterCheckBox             matlab.ui.control.CheckBox
        FilterCheckBox               matlab.ui.control.CheckBox
        fhEditField                  matlab.ui.control.NumericEditField
        fhEditFieldLabel             matlab.ui.control.Label
        flEditField                  matlab.ui.control.NumericEditField
        flEditFieldLabel             matlab.ui.control.Label
        FilteringLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        TimeScope % Description
        SpectrumAnalyzer % Description
        SpikeDetector % Description
        AudioPlayer % Description
        PSTH_Plotter % Description

        Parent
    end  
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.closeme();
        end

        % Value changed function: flEditField
        function flEditFieldValueChanged(app, event)
            app.update_filters();
        end

        % Value changed function: fhEditField
        function fhEditFieldValueChanged(app, event)
            app.update_filters();
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            app.update_filters();
        end

        % Value changed function: HzFilterCheckBox
        function HzFilterCheckBoxValueChanged(app, event)
            app.update_filters();
        end

        % Value changed function: FilterCheckBox
        function FilterCheckBoxValueChanged(app, event)
            app.update_filters();
        end

        % Value changed function: ShownSpikesEditField
        function ShownSpikesEditFieldValueChanged(app, event)
            app.set_detector_props();            
        end

        % Value changed function: ThresholduVSlider
        function ThresholduVSliderValueChanged(app, event)
            app.ThresholduVEditField.Value = app.ThresholduVSlider.Value; 
            app.set_detector_props();            
        end

        % Value changed function: ThresholduVEditField
        function ThresholduVEditFieldValueChanged(app, event)
            app.ThresholduVSlider.Value = app.ThresholduVEditField.Value; 
            app.set_detector_props();            
        end

        % Selection changed function: ThresholdSignButtonGroup
        function ThresholdSignButtonGroupSelectionChanged(app, event)
            app.set_detector_props();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BirdsLabNeuralToolboxUIUIFigure = uifigure('Visible', 'off');
            app.BirdsLabNeuralToolboxUIUIFigure.Name = 'BirdsLab Neural ToolboxUI';
            app.FilteringLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.FilteringLabel.Text = 'Filtering';
            app.flEditFieldLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.flEditFieldLabel.Text = 'fl';
            app.flEditField = uieditfield(app.BirdsLabNeuralToolboxUIUIFigure, 'numeric');
            app.flEditField.Limits = [0.1 Inf];
            app.flEditField.ValueChangedFcn = createCallbackFcn(app, @flEditFieldValueChanged, true);
            app.flEditField.Value = 350;
            app.fhEditFieldLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.fhEditFieldLabel.Text = 'fh';
            app.fhEditField = uieditfield(app.BirdsLabNeuralToolboxUIUIFigure, 'numeric');
            app.fhEditField.Limits = [0.15 Inf];
            app.fhEditField.ValueChangedFcn = createCallbackFcn(app, @fhEditFieldValueChanged, true);
            app.fhEditField.Value = 5000;
            app.FilterCheckBox = uicheckbox(app.BirdsLabNeuralToolboxUIUIFigure);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterCheckBoxValueChanged, true);
            app.FilterCheckBox.Text = 'Filter';
            app.FilterCheckBox.Value = true;
            app.HzFilterCheckBox = uicheckbox(app.BirdsLabNeuralToolboxUIUIFigure);
            app.HzFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @HzFilterCheckBoxValueChanged, true);
            app.HzFilterCheckBox.Text = '50 Hz Filter';
            app.HzFilterCheckBox.Value = true;
            app.FilterOrderEditFieldLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.BirdsLabNeuralToolboxUIUIFigure, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 6;
            app.SoundCheckBox = uicheckbox(app.BirdsLabNeuralToolboxUIUIFigure);
            app.SoundCheckBox.Text = 'Sound';
            app.SoundCheckBox.Value = true;
            app.ChannelSelectionButtonGroup = uibuttongroup(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ChannelSelectionButtonGroup.Title = 'Channel Selection';
            app.Channel1Button = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.Channel1Button.Text = 'Channel 1';
            app.Channel1Button.Value = true;
            app.Channel2Button = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.Channel2Button.Text = 'Channel 2';
            app.Channel3Button = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.Channel3Button.Text = 'Channel 3';
            app.Channel4Button = uiradiobutton(app.ChannelSelectionButtonGroup);
            app.Channel4Button.Text = 'Channel 4';
            app.TimeScopeSwitchLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.TimeScopeSwitchLabel.Text = 'TimeScope';
            app.TimeScopeSwitch = uiswitch(app.BirdsLabNeuralToolboxUIUIFigure, 'slider');
            app.TimeScopeSwitch.Value = 'On';
            app.SpectrumAnalyzerLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerSwitch = uiswitch(app.BirdsLabNeuralToolboxUIUIFigure, 'slider');
            app.SpikeDetectorLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.SpikeDetectorLabel.Text = 'Spike Detector';
            app.SpikeDetectorSwitch = uiswitch(app.BirdsLabNeuralToolboxUIUIFigure, 'slider');
            app.SpikeDetectorSwitch.Value = 'On';
            app.DeviceDropDownLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.DeviceDropDownLabel.Enable = 'off';
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.BirdsLabNeuralToolboxUIUIFigure);
            app.DeviceDropDown.Items = {'Neurologger 1', 'Neurologger 2', 'Resana'};
            app.DeviceDropDown.Enable = 'off';
            app.DeviceDropDown.Value = 'Neurologger 1';
            app.StopButton = uibutton(app.BirdsLabNeuralToolboxUIUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ShownSpikesEditFieldLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ShownSpikesEditFieldLabel.Text = 'ShownSpikes';
            app.ShownSpikesEditField = uieditfield(app.BirdsLabNeuralToolboxUIUIFigure, 'numeric');
            app.ShownSpikesEditField.Limits = [1 499];
            app.ShownSpikesEditField.RoundFractionalValues = 'on';
            app.ShownSpikesEditField.ValueChangedFcn = createCallbackFcn(app, @ShownSpikesEditFieldValueChanged, true);
            app.ShownSpikesEditField.Value = 20;
            app.OnlineSpikeDetectorLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.OnlineSpikeDetectorLabel.Text = 'Online Spike Detector';
            app.rev17Label = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.rev17Label.Text = 'rev 1.7';
            app.DevelopedbyBirdsLabSchoolofCognitiveSciencesIPMLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.DevelopedbyBirdsLabSchoolofCognitiveSciencesIPMLabel.Text = 'Developed by Birds Lab, School of Cognitive Sciences, IPM';
            app.PSTHSwitchLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.PSTHSwitchLabel.Text = 'PSTH';
            app.PSTHSwitch = uiswitch(app.BirdsLabNeuralToolboxUIUIFigure, 'slider');
            app.ThresholduVSliderLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ThresholduVSliderLabel.Text = 'Threshold (uV)';
            app.ThresholduVSlider = uislider(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ThresholduVSlider.Limits = [0.001 150];
            app.ThresholduVSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholduVSliderValueChanged, true);
            app.ThresholduVSlider.Value = 50;
            app.SpikeDetectionLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.SpikeDetectionLabel.Text = 'Spike Detection';
            app.ThresholduVEditFieldLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ThresholduVEditFieldLabel.Text = 'Threshold (uV)';
            app.ThresholduVEditField = uieditfield(app.BirdsLabNeuralToolboxUIUIFigure, 'numeric');
            app.ThresholduVEditField.Limits = [0.001 150];
            app.ThresholduVEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholduVEditFieldValueChanged, true);
            app.ThresholduVEditField.Value = 50;
            app.ISIPlotSwitchLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ISIPlotSwitchLabel.Enable = 'off';
            app.ISIPlotSwitchLabel.Text = 'ISI Plot';
            app.ISIPlotSwitch = uiswitch(app.BirdsLabNeuralToolboxUIUIFigure, 'slider');
            app.ISIPlotSwitch.Enable = 'off';
            app.SignalLabel = uilabel(app.BirdsLabNeuralToolboxUIUIFigure);
            app.SignalLabel.Text = 'Signal';
            app.ThresholdSignButtonGroup = uibuttongroup(app.BirdsLabNeuralToolboxUIUIFigure);
            app.ThresholdSignButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdSignButtonGroupSelectionChanged, true);
            app.ThresholdSignButtonGroup.Title = 'Threshold Sign';
            app.NegativeButton = uiradiobutton(app.ThresholdSignButtonGroup);
            app.NegativeButton.Text = 'Negative';
            app.NegativeButton.Value = true;
            app.PositiveButton = uiradiobutton(app.ThresholdSignButtonGroup);
            app.PositiveButton.Text = 'Positive';
            app.BothButton = uiradiobutton(app.ThresholdSignButtonGroup);
            app.BothButton.Text = 'Both';
            app.ResetButton = uibutton(app.BirdsLabNeuralToolboxUIUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.BirdsLabNeuralToolboxUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BirdsLabNeuralToolboxUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BirdsLabNeuralToolboxUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BirdsLabNeuralToolboxUIUIFigure)
        end
    end
end